classdef Brazo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        Elemento1SliderLabel  matlab.ui.control.Label
        Elemento1Slider       matlab.ui.control.Slider
        Elemento2SliderLabel  matlab.ui.control.Label
        Elemento2Slider       matlab.ui.control.Slider
    end


    properties (Access = private)
        EjeI=0;
        EjeII=0;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XLim=[-10,200];
            app.UIAxes.YLim=[-200,200];
            brazo2gdl(app.UIAxes,app.EjeI,app.EjeII);
        end

        % Value changed function: Elemento1Slider
        function Elemento1SliderValueChanged(app, event)
            app.EjeI = app.Elemento1Slider.Value;
            brazo2gdl(app.UIAxes,app.EjeI,app.EjeII);
        end

        % Value changed function: Elemento2Slider
        function Elemento2SliderValueChanged(app, event)
            app.EjeII = app.Elemento2Slider.Value;
            brazo2gdl(app.UIAxes,app.EjeI,app.EjeII);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Elemento1SliderLabel = uilabel(app.UIFigure);
            app.Elemento1SliderLabel.Text = 'Elemento 1';
            app.Elemento1Slider = uislider(app.UIFigure);
            app.Elemento1Slider.Limits = [0 90];
            app.Elemento1Slider.ValueChangedFcn = createCallbackFcn(app, @Elemento1SliderValueChanged, true);
            app.Elemento1Slider.Value = 90;
            app.Elemento2SliderLabel = uilabel(app.UIFigure);
            app.Elemento2SliderLabel.Text = 'Elemento 2';
            app.Elemento2Slider = uislider(app.UIFigure);
            app.Elemento2Slider.Limits = [-180 -90];
            app.Elemento2Slider.ValueChangedFcn = createCallbackFcn(app, @Elemento2SliderValueChanged, true);
            app.Elemento2Slider.Value = -180;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Brazo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end